classdef Peter_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StopButton              matlab.ui.control.Button
        TextArea                matlab.ui.control.TextArea
        StartPETERButton        matlab.ui.control.Button
        KpEditField             matlab.ui.control.NumericEditField
        KpEditFieldLabel        matlab.ui.control.Label
        DesiredHEditField       matlab.ui.control.NumericEditField
        DesiredHEditFieldLabel  matlab.ui.control.Label
        DesiredYEditField       matlab.ui.control.NumericEditField
        DesiredYEditFieldLabel  matlab.ui.control.Label
        DesiredXEditField       matlab.ui.control.NumericEditField
        DesiredXEditFieldLabel  matlab.ui.control.Label
        UIAxes_3                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ser
        stopFlag 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartPETERButton
        function StartPETERButtonPushed(app, event)
try
    % Kullanıcıdan istenen değerleri al
    desired_x = app.DesiredXEditField.Value;
    desired_y = app.DesiredYEditField.Value;
    desired_h = app.DesiredHEditField.Value;
    Kp = app.KpEditField.Value;
    L = 170;

    % Sabitler
    err_h_tol = 1.2;
    err_pos_norm_tol = 1.2;
    max_iter = 20;
    total_duration = 0;

    % Debug mesaj listesi
    debug_msgs = {};
    app.stopFlag = false;

    debug_msgs{end+1} = 'Peter Started.';

    % Serial port kontrolü ve açılması
    if isempty(app.ser) || ~isvalid(app.ser)
        app.ser = serialport("COM7", 115200);
        pause(2);
        debug_msgs{end+1} = 'Serial port opened.';
    end

    % Safety: tüm vanaları söndür
    write_one_valve_millis(app.ser, 1, -5000);
    write_one_valve_millis(app.ser, 2, -5000);
    write_one_valve_millis(app.ser, 3, -5000);
    disp("Deflating valves...");
    pause(5);

    % İlk sensör okuması
    [~, pitch0, roll0, h0] = read_sensors(app.ser, true);
    measured_pitch = pitch0;
    measured_roll  = roll0;
    measured_h     = h0;

    [desired_pitch, desired_roll, desired_hh] = xyh_to_prh(desired_x, desired_y, desired_h);
    [hA_des, hB_des, hC_des] = prh_to_leg_heights(desired_pitch, desired_roll, desired_hh);

    % 3D visualization için başlangıç üçgeni
    [A0, B0, C0] = prh_to_triangle_vertices(pitch0, roll0, h0, L);
    cla(app.UIAxes_3);
    hold(app.UIAxes_3, 'on');
    xlabel(app.UIAxes_3, 'X (mm)');
    ylabel(app.UIAxes_3, 'Y (mm)');
    zlabel(app.UIAxes_3, 'H (mm)');
    title(app.UIAxes_3, 'PETER');
    view(app.UIAxes_3, 3);
    grid(app.UIAxes_3, 'on');
    axis(app.UIAxes_3, 'equal');

    still_in_tolerance = false;
    iter = 0;

    while true
        if app.stopFlag
            debug_msgs{end+1} = 'STOP pressed. Control loop is interrupted.';
            app.TextArea.Value = debug_msgs;
            break
        end

        iter = iter + 1;
        [~, pitch_new, roll_new, h_new] = read_sensors_filtered(app.ser, true);
        measured_pitch = pitch_new;
        measured_roll  = roll_new;
        measured_h     = h_new;

        [x_c, y_c, h_c] = prh_to_xyh(measured_pitch, measured_roll, measured_h);

        pos_error = [desired_x - x_c, desired_y - y_c, desired_h - h_c];
        pos_norm = norm(pos_error(1:2));
        h_error = pos_error(3);

        [hA_meas, hB_meas, hC_meas] = prh_to_leg_heights(measured_pitch, measured_roll, measured_h);

        % Visualization güncelle
        [A, B, C] = prh_to_triangle_vertices(measured_pitch, measured_roll, measured_h, L);
        cla(app.UIAxes_3);
        fill3(app.UIAxes_3, [A0(1) B0(1) C0(1) A0(1)], [A0(2) B0(2) C0(2) A0(2)], [A0(3) B0(3) C0(3) A0(3)], ...
            'b', 'FaceAlpha', 0.15, 'EdgeColor', 'b', 'LineWidth', 1.5);
        fill3(app.UIAxes_3, [A(1) B(1) C(1) A(1)], [A(2) B(2) C(2) A(2)], [A(3) B(3) C(3) A(3)], ...
            'r', 'FaceAlpha', 0.22, 'EdgeColor', 'r', 'LineWidth', 2);
        center0 = mean([A0;B0;C0],1);
        center  = mean([A;B;C],1);
        plot3(app.UIAxes_3, center0(1), center0(2), center0(3), 'bo', 'MarkerFaceColor', 'b', 'MarkerSize', 10);
        plot3(app.UIAxes_3, center(1), center(2), center(3), 'ro', 'MarkerFaceColor', 'r', 'MarkerSize', 10);
        legend(app.UIAxes_3, {'Initial','Current'},'Location','best');
        drawnow;

        msg = sprintf(['[Step %d]\nCurrent Leg Heights:    [%.2f %.2f %.2f]\n' ...
            'Current PRH:            [%.2f %.2f %.2f]\nCurrent Position:       [%.2f %.2f %.2f]\n' ...
            'Position error:         [%.2f %.2f] (norm=%.2f)\nH error: [%.2f]\nTotal_duration: [%.2f]\n'], ...
            iter, hA_meas, hB_meas, hC_meas, measured_pitch, measured_roll, measured_h, ...
            x_c, y_c, h_c, pos_error(1), pos_error(2), pos_norm, h_error, total_duration);
        debug_msgs{end+1} = msg;
        app.TextArea.Value = debug_msgs;

        % Tolerans bandındaysa bekle, değilse kontrol algoritmasını çalıştır
        if abs(pos_norm) < err_pos_norm_tol && abs(h_error) < err_h_tol
            if ~still_in_tolerance
                debug_msgs{end+1} = 'In Tolerance';
                app.TextArea.Value = debug_msgs;
                still_in_tolerance = true;
            end
            pause(0.5);
            continue
        else
            still_in_tolerance = false;
            debug_msgs{end+1} = 'Out of Tolerance';
            app.TextArea.Value = debug_msgs;

            for k = 1:max_iter
                if app.stopFlag
                    debug_msgs{end+1} = 'STOP pressed. Control loop is interrupted.';
                    app.TextArea.Value = debug_msgs;
                    break
                end

                [hA_meas, hB_meas, hC_meas] = prh_to_leg_heights(measured_pitch, measured_roll, measured_h);

                err_A = hA_des - hA_meas;
                err_B = hB_des - hB_meas;
                err_C = hC_des - hC_meas;

                tA = Kp * err_A;
                tB = Kp * err_B;
                tC = Kp * err_C;

                write_one_valve_millis(app.ser, 1, tC);
                write_one_valve_millis(app.ser, 2, tA);
                write_one_valve_millis(app.ser, 3, tB);

                pause_duration = max([200, abs(tA), abs(tB), abs(tC)]) / 1000;
                total_duration = total_duration + pause_duration;
                pause(pause_duration);

                [~, pitch_new, roll_new, h_new] = read_sensors_filtered(app.ser, true);
                measured_pitch = pitch_new;
                measured_roll  = roll_new;
                measured_h     = h_new;

                [x_c, y_c, h_c] = prh_to_xyh(measured_pitch, measured_roll, measured_h);
                pos_error = [desired_x - x_c, desired_y - y_c, desired_h - h_c];
                pos_norm = norm(pos_error(1:2));
                h_error = pos_error(3);

                msg2 = sprintf(['[CONTROL Step %d]\nValve times (ms):       [%.2f %.2f %.2f]\n' ...
                    'Desired Leg Heights:    [%.2f %.2f %.2f]\nCurrent Leg Heights:    [%.2f %.2f %.2f]\n' ...
                    'Desired PRH:            [%.2f %.2f %.2f]\nCurrent PRH:            [%.2f %.2f %.2f]\n' ...
                    'Current Position:       [%.2f %.2f %.2f]\nPosition error:         [%.2f %.2f] (norm=%.2f)\nH error: [%.2f]\nTotal_duration: [%.2f]\n'], ...
                    k, tA, tB, tC, hA_des, hB_des, hC_des, hA_meas, hB_meas, hC_meas, ...
                    desired_pitch, desired_roll, desired_h, measured_pitch, measured_roll, measured_h, ...
                    x_c, y_c, h_c, pos_error(1), pos_error(2), pos_norm, h_error, total_duration);
                debug_msgs{end+1} = msg2;
                app.TextArea.Value = debug_msgs;

                if abs(pos_norm) < err_pos_norm_tol && abs(h_error) < err_h_tol
                    debug_msgs{end+1} = 'Tolerance bandına tekrar girildi.';
                    app.TextArea.Value = debug_msgs;
                    iter = 0;
                    total_duration = 0;
                    break
                end
            end
        end
    end

catch ME
    app.TextArea.Value = ['HATA: ' getReport(ME)];
    if ~isempty(app.ser) && isvalid(app.ser)
        clear app.ser
    end
end


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
    app.stopFlag = true; 
    try
        if ~isempty(app.ser) && isvalid(app.ser)
            write_one_valve_millis(app.ser, 1, -5000);
            write_one_valve_millis(app.ser, 2, -5000);
            write_one_valve_millis(app.ser, 3, -5000);
            app.TextArea.Value = {'STOP pressed! All valves are set to exhaust (-5000 ms).'};
            drawnow;
            pause(5)
        else
            app.TextArea.Value = {'STOP pressed! But serial port is not active.'};
            drawnow;
        end
    catch ME
        app.TextArea.Value = {'STOP ERROR:', ME.message};
        drawnow;
    end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.DesiredXEditFieldLabel = uilabel(app.UIFigure);
            app.DesiredXEditFieldLabel.Text = 'Desired X';
            app.DesiredXEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesiredYEditFieldLabel = uilabel(app.UIFigure);
            app.DesiredYEditFieldLabel.Text = 'Desired Y';
            app.DesiredYEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesiredHEditFieldLabel = uilabel(app.UIFigure);
            app.DesiredHEditFieldLabel.Text = 'Desired H';
            app.DesiredHEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesiredHEditField.Limits = [0 80];
            app.KpEditFieldLabel = uilabel(app.UIFigure);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.UIFigure, 'numeric');
            app.KpEditField.Limits = [0 150];
            app.KpEditField.Value = 60;
            app.StartPETERButton = uibutton(app.UIFigure, 'push');
            app.StartPETERButton.ButtonPushedFcn = createCallbackFcn(app, @StartPETERButtonPushed, true);
            app.StartPETERButton.Text = 'Start PETER';
            app.TextArea = uitextarea(app.UIFigure);
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Peter_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end